classdef Main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AIMUIFigure        matlab.ui.Figure
        Panel              matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        Image              matlab.ui.control.Image
        GUIApplicationforIsothermFittingandBreakthroughSimulationsLabel  matlab.ui.control.Label
        LaunchButton       matlab.ui.control.Button
        credits            matlab.ui.control.Label
        SelectModulePanel  matlab.ui.container.Panel
        DescriptionName    matlab.ui.control.Label
        DescriptionBox     matlab.ui.control.Label
        IsoFitButton       matlab.ui.control.Button
        BreakLabButton     matlab.ui.control.Button
        HeatFitButton      matlab.ui.control.Button
        MixPredButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        iso_fit         % App for isotherm fitting
        iso_fit_diffT   % App for isotherm fitting for diff Temperatures
        mixture_pred    % App for mixture isotherm prediction
        adsorption_app  % App to perform adsorption breakthrough
        sesmr_app       % App to perform sesmr breakthrough
        mode_flag       % Flag to keep track of mode choosen
    end
    
    methods (Access = private)
        
        function [] = format_button(app, button_to_format)
            % Get all UI components in the app
            allComponents = app.AIMUIFigure.Children;
            
            % Filter out the buttons
            buttonList = findobj(allComponents, 'Type', 'uibutton');

            for i=1:numel(buttonList)
                current_button = buttonList(i);
                current_button.BackgroundColor = [1.0 1.0 1.0];
            end

            button_to_format.BackgroundColor = [0.88 0.95 1.00];
        end
        %
         %% Function to adjust the size of figure
        function [] = adjust_size(app)
            app.AIMUIFigure.Units = "normalized";
            app.AIMUIFigure.Position(1) = 0.1;
            app.AIMUIFigure.Position(2) = 0.05;
            app.AIMUIFigure.Units = "pixels";
        end
        %
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AIMUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AIMUIFigure.Position = [left bottom width height];
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_size();
            app.adjust_uifig();
            app.DescriptionName.Text = "";
            app.DescriptionBox.Text = "Please choose one of the mode...";
            app.mode_flag = 0;
        end

        % Button pushed function: IsoFitButton
        function IsoFitButtonPushed(app, event)
            app.mode_flag = 1;
            app.DescriptionName.Text = "Isotherm Fitting";
            app.DescriptionBox.Text = "GUI module for fitting various isotherm models to your isotherm data.";

            app.format_button(app.IsoFitButton);
        end

        % Button pushed function: HeatFitButton
        function HeatFitButtonPushed(app, event)
            app.mode_flag = 2;
            app.DescriptionName.Text = "Isotherm Fitting and Heat of Adsorption using Multi Temperature Isotherm Data";
            app.DescriptionBox.Text = "GUI module for fitting various isotherm models and predicting heat of adsorption" + ...
                                        " with Clausius-Clapeyron or Virial equation using multi temprature isotherm data.";

            app.format_button(app.HeatFitButton);
        end

        % Button pushed function: MixPredButton
        function MixPredButtonPushed(app, event)
            app.mode_flag = 3;
            app.DescriptionName.Text = "Mixture Adsorption Isotherm";
            app.DescriptionBox.Text = "GUI module for predicting mixture adsorption uptakes with Ideal Adsorbed Solution " + ...
                                       "Theory (IAST) model or Extended Dual-site Langmuir (EDSL) models.";

            app.format_button(app.MixPredButton);
        end

        % Button pushed function: BreakLabButton
        function BreakLabButtonPushed(app, event)
            app.mode_flag = 4;
            app.DescriptionName.Text = "Multicomponent Adsorption Breakthrough";
            app.DescriptionBox.Text = "GUI module for simulating breakthrough curves for multicomponent adsorption " + ...
                                        "upto 5 components with Ideal Adsorbed Solution " + ...
                                        "Theory (IAST) model or Extended Dual-site Langmuir (EDSL) models.";

            app.format_button(app.BreakLabButton);
        end

        % Button pushed function: LaunchButton
        function LaunchButtonPushed(app, event)
            if app.mode_flag
                switch (app.mode_flag)
                    case 1
                        app.iso_fit = Isotherm_fit_GUI();
                    case 2
                        app.iso_fit_diffT = Isotherm_fit_GUI_diffT();
                    case 3
                        app.mixture_pred = mixture_prediction();
                    case 4
                        app.adsorption_app = Adsorption_GUI();                  
                end
            else
                app.DescriptionBox.Text = "Please choose mode first...!";
            end
        end

        % Close request function: AIMUIFigure
        function AIMUIFigureCloseRequest(app, event)
            delete(app.adsorption_app)
            delete(app.sesmr_app)
            
            YN = uiconfirm(app.AIMUIFigure,'Do you want to close the app?', 'Close request');
            if strcmpi(YN,'OK')
                delete(app)
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AIMUIFigure = uifigure('Visible', 'off');
            app.AIMUIFigure.Name = 'AIM';
            app.AIMUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.AIMUIFigure.CloseRequestFcn = createCallbackFcn(app, @AIMUIFigureCloseRequest, true);
            app.Panel = uipanel(app.AIMUIFigure);
            app.SelectModulePanel = uipanel(app.Panel);
            app.SelectModulePanel.Title = 'Select Module';
            app.MixPredButton = uibutton(app.SelectModulePanel, 'push');
            app.MixPredButton.ButtonPushedFcn = createCallbackFcn(app, @MixPredButtonPushed, true);
            app.MixPredButton.Text = 'MixPred';
            app.HeatFitButton = uibutton(app.SelectModulePanel, 'push');
            app.HeatFitButton.ButtonPushedFcn = createCallbackFcn(app, @HeatFitButtonPushed, true);
            app.HeatFitButton.Text = 'HeatFit';
            app.BreakLabButton = uibutton(app.SelectModulePanel, 'push');
            app.BreakLabButton.ButtonPushedFcn = createCallbackFcn(app, @BreakLabButtonPushed, true);
            app.BreakLabButton.Text = 'BreakLab';
            app.IsoFitButton = uibutton(app.SelectModulePanel, 'push');
            app.IsoFitButton.ButtonPushedFcn = createCallbackFcn(app, @IsoFitButtonPushed, true);
            app.IsoFitButton.Text = 'IsoFit';
            app.DescriptionBox = uilabel(app.SelectModulePanel);
            app.DescriptionBox.Text = '';
            app.DescriptionName = uilabel(app.SelectModulePanel);
            app.DescriptionName.Text = '';
            app.credits = uilabel(app.Panel);
            app.credits.Text = 'Molecular Thermodynamics and Advanced Processes Lab, Pusan National University';
            app.LaunchButton = uibutton(app.Panel, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchButtonPushed, true);
            app.LaunchButton.Icon = fullfile(pathToMLAPP, 'launch_button_arrrow.png');
            app.LaunchButton.Text = 'Launch';
            app.GUIApplicationforIsothermFittingandBreakthroughSimulationsLabel = uilabel(app.Panel);
            app.GUIApplicationforIsothermFittingandBreakthroughSimulationsLabel.Text = 'GUI Application for Isotherm Fitting, Mixture Isotherms, and Fixed-Bed Breakthrough Simulations';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'AIM_logo.png';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'AIM';
            % Show the figure after all components are created
            app.AIMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_app

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AIMUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AIMUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AIMUIFigure)
        end
    end
end