classdef p_sat_info < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdditionalInformationUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = private)
        text_1 = sprintf(['Some of the isotherm models, such as Do-Do, Klotz, and\n Dubinin-Astakhov' ...
            ' require relative pressure $x$ instead of actual pressure $P$']);
        % text_1 = ['$$Some of the isotherm models, such as Do-Do, Klotz, and Dubinin-Astakhov' ...
        %     ' require relative pressure x instead of actual pressure P\\ .The relative pressure' ...
        %     ' x is a dimensionaless pressure calculated as follows:\\ '...
        %     'x=\frac{P}{P_{0}}$$'];

        text_2 = sprintf('Where $x=\\frac{P}{P_{0}},\\quad P_{0}=\\;$Saturation/Total Pressure');
        text_3 = sprintf("You can specify $P_{0}$:\n" + ...
            "1). In the app itself using the $P_{0}$ editfield.\n" + ...
            "2). In the isotherm data file using the '#sat_pressure' tag.\n" + ...
            "3). In case of .aif file use the '_adsorp_p0' data name to specify $P_{0}$.");
        text_4 = sprintf('In the case of data file already containing relative pressure, please specify\n$P_{0}=1.0$.');
    end
    
    methods (Access = private)  
        %% Function to update the uifigure for different devices
        function [] = adjust_uifig(app)
            % Get the device resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            default_position = app.AdditionalInformationUIFigure.Position;
            
            if default_position(3) > screenWidth || default_position(4) > screenHeight
                left = screenWidth*0.1;
                bottom = screenHeight*0.1;
                width = screenWidth*0.85;
                height = screenHeight*0.85;
                drawnow;
                app.AdditionalInformationUIFigure.Position = [left bottom width height];
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.adjust_uifig();
            app.Label.Text = app.text_1;
            app.Label_2.Text = app.text_2;
            app.Label_3.Text = app.text_3;
            app.Label_4.Text = app.text_4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.AdditionalInformationUIFigure = uifigure('Visible', 'off');
            app.AdditionalInformationUIFigure.Name = 'Additional Information';
            app.AdditionalInformationUIFigure.Icon = fullfile(pathToMLAPP, 'AIM_logo.png');
            app.GridLayout = uigridlayout(app.AdditionalInformationUIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Label = uilabel(app.Panel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '';
            % Show the figure after all components are created
            app.AdditionalInformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p_sat_info

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdditionalInformationUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.AdditionalInformationUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdditionalInformationUIFigure)
        end
    end
end